classdef winAppColetaV2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        GridLayout9         matlab.ui.container.GridLayout
        GridLayout8         matlab.ui.container.GridLayout
        Button_Settings     matlab.ui.control.Button
        Separator4          matlab.ui.control.Image
        Button_New          matlab.ui.control.Button
        Separator3          matlab.ui.control.Image
        Button_LayoutRight  matlab.ui.control.Button
        Button_LayoutLeft   matlab.ui.control.Button
        Button_LayoutTop    matlab.ui.control.Button
        Status              matlab.ui.control.Label
        Separator2          matlab.ui.control.Image
        Separator1          matlab.ui.control.Image
        Button_log          matlab.ui.control.Button
        Button_Del          matlab.ui.control.Button
        Button_Edit         matlab.ui.control.Button
        Button_Stop         matlab.ui.control.Button
        Button_Play         matlab.ui.control.Button
        GridLayout          matlab.ui.container.GridLayout
        Table               ccTools.Table
        MetaData_Panel      matlab.ui.container.Panel
        MetaData_Grid       matlab.ui.container.GridLayout
        MetaData            matlab.ui.control.HTML
        MetadadosrelacionadosfaixaselecionadaLabel  matlab.ui.control.Label
        GridLayout3         matlab.ui.container.GridLayout
        Panel_7             matlab.ui.container.Panel
        GridLayout2_6       matlab.ui.container.GridLayout
        lastMask_text       matlab.ui.control.Label
        lastMask_label      matlab.ui.control.Label
        Panel_6             matlab.ui.container.Panel
        GridLayout2_3       matlab.ui.container.GridLayout
        GridLayout7         matlab.ui.container.GridLayout
        lastGPS_color       matlab.ui.control.Lamp
        lastGPS_text        matlab.ui.control.Label
        lastGPS_label       matlab.ui.control.Label
        Panel_5             matlab.ui.container.Panel
        GridLayout2_2       matlab.ui.container.GridLayout
        Channel             matlab.ui.control.Label
        ChannelLabel        matlab.ui.control.Label
        tool_REC            matlab.ui.control.Image
        Tree                matlab.ui.container.Tree
        FaixasdefrequnciarelacionadastarefaselecionadaLabel  matlab.ui.control.Label
        Plot_Panel          matlab.ui.container.Panel
    end

    
    properties (Access = public)
        %-----------------------------------------------------------------%
        % CONSTANTS (AT LEAST NOT EDITABLE BY THIS APP)
        %-----------------------------------------------------------------%        
        RootFolder
        General

        EMSatObj
        EB500Obj

        taskList
        scpiList
        instrInfo

        %-----------------------------------------------------------------%
        % COMPONENTS CREATED PROGRAMMATICALLY
        %-----------------------------------------------------------------%
        axes1
        axes2

        %-----------------------------------------------------------------%
        % HANDLES
        %-----------------------------------------------------------------%
        instrHandles = table('Size', [0, 5],                                                    ...
                             'VariableTypes', {'string', 'string', 'string', 'cell', 'double'}, ...
                             'VariableNames', {'Type', 'IDN', 'Socket', 'Handle', 'Status'})
        udpPortArray = {}

        auxiliarWin1 = [] % winSettings
        auxiliarWin2 = [] % winAddTask        

        %-----------------------------------------------------------------%
        % MAIN VARIABLES
        %-----------------------------------------------------------------%
        specObj      = class.specClass
        revisitObj
        timerObj

        Flag_running = 0
        Flag_editing = 0
    end

    
    methods (Access = private)
        %-----------------------------------------------------------------%
        % STARTUP
        %-----------------------------------------------------------------%
        function startup_WindowPosition(app)
            mainMonitor = get(0, 'MonitorPositions');
            [~, idx]    = max(mainMonitor(:,3));
            mainMonitor = mainMonitor(idx,:);
            
            app.UIFigure.Position(3:4) = class.Constants.windowSize;
            app.UIFigure.Position(1:2) = [mainMonitor(1)+round((mainMonitor(3)-app.UIFigure.Position(3))/2), ...
                                          mainMonitor(2)+round((mainMonitor(4)-app.UIFigure.Position(4)-30)/2)];

            ccTools.compCustomization(app.UIFigure, 'windowMinSize', class.Constants.windowMinSize);
        end

        %-----------------------------------------------------------------%
        function startup_AxesCreation(app)
            t = tiledlayout(app.Plot_Panel, 3, 1, "Padding", "tight", "TileSpacing", "compact");

            % app.UIAxes1
            app.axes1 = uiaxes(t);
            app.axes1.Layout.Tile = 1;
            set(app.axes1, Color = [0.94, .94, .94], FontSize = 8)
            ylabel(app.axes1, 'Nível (dB)')

            % app.UIAxes2
            app.axes2 = uiaxes(t);
            app.axes2.Layout.Tile = 2;
            app.axes2.Layout.TileSpan  = [2,1];
            set(app.axes2, Color = [0.94, .94, .94], FontSize = 8)
            xlabel(app.axes2, 'Frequência (MHz)')
            ylabel(app.axes2, 'Amostras')
        end

        %-----------------------------------------------------------------%
        function startup_ConfigFiles(app)
            % app.General
            global appGeneral
            tempObj = jsondecode(fileread(fullfile(app.RootFolder, 'Settings', 'GeneralSettings.json')));
            app.General = tempObj;

            userpaths = {fullfile(getenv('USERPROFILE'), 'Documents'); ...
                         fullfile(getenv('USERPROFILE'), 'Downloads')};

            if ~isempty(app.General.userPath)
                userpaths(end+1) = {app.General.userPath};
            end

            for ii = numel(userpaths):-1:1
                if ~isfolder(userpaths{ii})
                    userpaths(ii) = [];
                end
            end
            app.General.userPath     = userpaths{end};
            app.General.RootFolder   = app.RootFolder;
            app.General.File.Version = class.Constants.fileVersion;

            appGeneral = app.General;

            % app.taskList
            taskTemp = jsondecode(fileread('taskList.json'));
            for ii = 1:numel(taskTemp)
                app.taskList{ii} = taskTemp(ii);
    
                if strcmp(app.taskList{ii}.Observation.Type, 'Duration') & isempty(app.taskList{ii}.Observation.Duration)
                    app.taskList{ii}.Observation.Duration = inf;
                end
    
                if ~any([app.taskList{ii}.Band.Enable])
                    app.taskList{ii}.Band(1).Enable = 1;
                end
            end

            % app.scpiList
            tempList = jsondecode(fileread('scpiList.json'));
            for ii = 1:numel(tempList)
                tempList(ii).Parameters = jsonencode(tempList(ii).Parameters);
            end

            app.scpiList     = struct2table(tempList, 'AsArray', true);
            app.scpiList.idx = (1:numel(tempList))';
            app.scpiList     = movevars(app.scpiList, 'idx', 'Before', 1);


            % app.instrInfo, app.EMSatObj & app.EB500Obj
            app.instrInfo = struct2table(jsondecode(fileread(fullfile(app.RootFolder, 'Settings', 'instrInfo.json'))));
            app.EMSatObj  = class.EMSatLib;
            app.EB500Obj  = class.EB500Lib;
        end

        %-----------------------------------------------------------------%
        function startup_TimerCreation(app)
            app.timerObj = timer("ExecutionMode", "fixedSpacing",        ...
                                 "BusyMode",      "queue",               ...
                                 "StartDelay",    timer_StartDelay(app), ...
                                 "Period",        900,                   ...
                                 "TimerFcn",      @(~,~)app.timer_RegularTaskCheck);
            start(app.timerObj)
        end


        %-----------------------------------------------------------------%
        % TIMER 
        %-----------------------------------------------------------------%
        function StartDelay = timer_StartDelay(app)
            StartDelay = 0;
            if mod(minute(datetime('now')), 15)
                StartDelay = (15 - mod(minute(datetime('now')), 15)) * 60;
            end
        end

        %-----------------------------------------------------------------%
        function timer_RegularTaskCheck(app)
            if ~app.Flag_running
                Flag = false;
                for ii = 1:numel(app.specObj)
                    if RegularTask_StatusTaskCheck(app, ii)
                        Flag = true;
                        break
                    end
                end

                if Flag
                    RegularTask_MainLoop(app)
                end
            end
        end


        %-----------------------------------------------------------------%
        % REGULAR TASK
        %-----------------------------------------------------------------%
        function RegularTask_MainLoop(app)
            stop(app.timerObj)
            
            app.Flag_running = 1;
            app.Flag_editing = 1;
            
            while app.Flag_running
                if app.Flag_editing
                    app.revisitObj = task_RevisitFactors(app.specObj);
                    if isempty(app.revisitObj.GlobalRevisitTime)
                        app.Flag_running = 0;
                        break
                    end
                    
                    nn = 0;
                    app.Flag_editing = 0;
                end

                sweepTic = tic;
                for ii = 1:numel(app.specObj)
                    if RegularTask_StatusTaskCheck(app, ii)
                        app.Flag_editing = 1;
                        break
                    end

                    if ~strcmp(app.specObj(ii).Status, 'Em andamento')
                        continue
                    end
                    
                    hReceiver  = app.specObj(ii).hReceiver;
                    hStreaming = app.specObj(ii).hStreaming;
                    hGPS       = app.specObj(ii).hGPS;

                    nBands = numel(app.specObj(ii).Band);    
                    for jj = 0:nBands
                        if mod(nn, app.revisitObj.Band(ii).RevisitFactors(jj+1)) || app.revisitObj.Band(ii).RevisitFactors(jj+1) == -1
                            continue
                        end
    
                        if jj == 0
                            app.specObj(ii).lastGPS = RegularTask_gpsData(app, ii, hReceiver, hGPS);

                        else
                            app.specObj(ii) = class.RFlookBinLib.CheckFile(app.specObj(ii), jj);
    
                            try
                                % ANTENNA SWITCH (IF APPLICABLE)
                                RegularTask_AntennaSwitch(app, ii)

                                % RECEIVER RECONFIGURATION (IF APPLICABLE)
                                if (nBands > 1) || (hReceiver.UserData.nTasks > 1)
                                    writeline(hReceiver, app.specObj(ii).Band(jj).scpiSet_Config);
                                    pause(.001)
                                    
                                    if ~isempty(app.specObj(ii).Band(jj).scpiSet_Att)
                                        writeline(hReceiver, app.specObj(ii).Band(jj).scpiSet_Att);
                                    end
                                end

                                attFactor = -1;
                                if ~isempty(app.specObj(ii).SCPI.scpiGet_Att)
                                    attFactor = str2double(writeread(hReceiver, app.specObj(ii).SCPI.scpiGet_Att));
                                end
                                
                                fileTrigger  = [];
                                brokeTrigger = [];
                                if isempty(app.specObj(ii).Band(jj).Mask)
                                    % SINGLE TRACE
                                    newArray = RegularTask_specData(app, ii, jj, hReceiver, hStreaming);
                                
                                else
                                    % BURST OF TRACES
                                    nSweeps  = app.General.FindPeaks.nSweeps;
                                    newArray = zeros(nSweeps, app.specObj(ii).Band(jj).DataPoints, 'single');                                    
                                    for kk = 1:nSweeps
                                        newArray(kk,:) = RegularTask_specData(app, ii, jj, hReceiver, hStreaming);
                                    end
                                    smoothedArray = mean(newArray);

                                    % METADATA UPDATE
                                    app.specObj(ii).Band(jj).Mask.Validations = app.specObj(ii).Band(jj).Mask.Validations + 1;
                                    
                                    brokeTrigger = false;
                                    switch app.specObj(ii).taskObj.General.Task.Band(jj).Trigger
                                        case 2;    fileTrigger = true;
                                        otherwise; fileTrigger = false;
                                    end

                                    % MASK BROKEN ANALISYS                                    
                                    validationArray = (smoothedArray - app.specObj(ii).Band(jj).Mask.Array) > 0;
                                    if any(validationArray)
                                        app.specObj(ii).Band(jj).Mask.BrokenArray = app.specObj(ii).Band(jj).Mask.BrokenArray + validationArray;

                                        peaksTable = task_FindPeaks(app.specObj(ii), jj, smoothedArray, validationArray, app.General.FindPeaks);
                                        if ~isempty(peaksTable)
                                            app.specObj(ii).Band(jj).Mask.BrokenCount = app.specObj(ii).Band(jj).Mask.BrokenCount + 1;
                                            app.specObj(ii).Band(jj).Mask.MainPeaks   = peaksTable;
                                            app.specObj(ii).Band(jj).Mask.TimeStamp   = datetime('now');
                                            
                                            brokeTrigger = true;
                                        end
                                    end

                                    newArray = newArray(end,:);
                                end

                                % WATERFALL MATRIX
                                idx = app.specObj(ii).Band(jj).Waterfall.idx + 1;
                                if idx > app.General.Waterfall.Depth; idx = 1;
                                end

                                app.specObj(ii).Band(jj).Waterfall.idx = idx;
                                app.specObj(ii).Band(jj).Waterfall.Matrix(idx,:) = newArray;

                                % PLOT, WRITEDSAMPLES & MASKINFO (IF APPLICABLE)
                                if (app.Table.Selection == ii) && (app.Tree.SelectedNodes.NodeData == jj)
                                    PlotTask(app, ii, jj, newArray)                                    
                                    if ~isempty(brokeTrigger)
                                        layoutFcn.lastMaskValidation(app, brokeTrigger, ii, jj)
                                    end
                                    drawnow
                                end

                                % FILE
                                if fileTrigger & ~brokeTrigger
                                    continue
                                else
                                    class.RFlookBinLib.EditFile(app.specObj(ii), jj, newArray, attFactor)
                                    app.specObj(ii).Band(jj).File.WritedSamples = app.specObj(ii).Band(jj).File.WritedSamples + 1;

                                    if (app.Table.Selection == ii) && (app.Tree.SelectedNodes.NodeData == jj)
                                        app.Channel.Text = string(app.specObj(ii).Band(jj).File.WritedSamples);
                                    end
                                    drawnow
                                end                                
    
                            catch ME
                                % CONTADOR DE ERROS - SE CHEGAR A 10
                                % SUCESSIVOS (PRECISA ZERAR SE DEU CERTO), FAZ-SE UMA CHECAGEM NAS
                                % COISAS.
                                getReport(ME)
                            end
                        end
                    end
                end
                
                nn = nn+1;
                pause(app.revisitObj.GlobalRevisitTime-toc(sweepTic))
            end

            set(app.timerObj, 'StartDelay', timer_StartDelay(app))
            start(app.timerObj)
        end


        %-----------------------------------------------------------------%
        function Flag = RegularTask_StatusTaskCheck(app, idx)
            Flag = false;
            switch app.specObj(idx).Status        
                case 'Em andamento'
                    if app.specObj(idx).Observation.EndTime < datetime('now')
                        Flag = true;

                        app.specObj(idx).Status = 'Concluída';
                        app.specObj(idx).hReceiver.UserData.nTasks = app.specObj(idx).hReceiver.UserData.nTasks-1;

                        for ii = 1:numel(app.specObj(idx).Band)
                            app.specObj(idx) = class.RFlookBinLib.CloseFile(app.specObj(idx), ii);
                            app.specObj(idx).Band(ii).Status = false;
                        end

                    else
                        if strcmp(app.specObj(idx).taskObj.General.Task.Observation.Type, 'Samples')
                            tempFlag = [];                            
                            for ii = 1:numel(app.specObj(idx).Band)
                                if app.specObj(idx).Band(ii).Status
                                    if app.specObj(idx).Band(ii).File.WritedSamples == app.specObj(idx).taskObj.General.Task.Band(ii).instrObservationSamples
                                        app.specObj(idx) = class.RFlookBinLib.CloseFile(app.specObj(idx), ii);
                                        app.specObj(idx).Band(ii).Status = false;
                                        tempFlag(end+1) = true;

                                    else
                                        tempFlag(end+1) = false;
                                    end
                                end
                            end

                            if all(tempFlag)
                                Flag = true;

                                app.specObj(idx).Status = 'Concluída';
                                app.specObj(idx).hReceiver.UserData.nTasks = app.specObj(idx).hReceiver.UserData.nTasks-1;

                            elseif any(tempFlag)
                                Flag = true;
                            end
                        end
                    end

                case {'Na fila', 'Erro'}
                    Timestamp = datetime('now');                    
                    if strcmp(app.specObj(idx).Status, 'Erro') 
                        if ~mod(minute(Timestamp), 15) 
                            if isnat(app.specObj(idx).Observation.StartUp)
                                app.specObj(idx).Observation.StartUp = Timestamp;
                            end
    
                            StartUp = app.specObj(idx).Observation.StartUp;
                            if isequal([year(Timestamp), month(Timestamp), day(Timestamp), hour(Timestamp), minute(Timestamp)], ...
                                    [year(StartUp), month(StartUp), day(StartUp), hour(StartUp), minute(StartUp)])
                                return
                            end
                        else
                            return
                        end
                    end

                    if app.specObj(idx).Observation.BeginTime < datetime('now')
                        switch app.specObj(idx).taskObj.General.Task.Observation.Type
                            case {'Duration', 'Time'}
                                if isnat(app.specObj(idx).Observation.EndTime) || (app.specObj(idx).Observation.EndTime > datetime('now'))
                                    Flag = true;
                                end

                            case 'Samples'
                                Flag = true;
                        end
                    end

                    if Flag
                        try
                            RegularTask_StartUp(app, idx);

                            app.specObj(idx).Status = 'Em andamento';
                            app.specObj(idx).hReceiver.UserData.nTasks   = app.specObj(idx).hReceiver.UserData.nTasks+1;
                            app.specObj(idx).hReceiver.UserData.SyncMode = app.specObj(idx).taskObj.Receiver.Sync;

                        catch ME
                            app.specObj(idx).LOG(end+1) = struct('type', 'error', 'time', datestr(now, 'dd/mm/yyyy HH:MM:SS'), 'msg', ME.message);
                            app.specObj(idx).Status = 'Erro';
                            Flag = false;
                        end
                    end
            end

            if Flag
                layoutFcn.tableBuilding(app, app.Table.Selection)
                TreeSelectionChanged(app)
            end
        end


        %-----------------------------------------------------------------%
        function gpsData = RegularTask_gpsData(app, idx, hReceiver, hGPS)
            gpsData = struct('Status', 0, 'Latitude', -1, 'Longitude', -1, 'TimeStamp', '');

            try
                switch app.specObj(idx).taskObj.General.Task.GPS.Type
                    case 'Built-in'; gpsData = task_gpsDecode(hReceiver);
                    case 'External'; gpsData = task_gpsReader(hGPS, 1);
                end
            catch
            end

            if isempty(gpsData.TimeStamp)
                gpsData.TimeStamp = datestr(now, 'dd/mm/yyyy HH:MM:ss');
            end

            if (app.Table.Selection == idx)
                layoutFcn.lastGPS(app, gpsData)
            end
        end
        
        
        %-----------------------------------------------------------------%
        function RegularTask_AntennaSwitch(app, idx)
            % PENDENTE!!!
            % Se não conseguir configurar adequadamente, retorna um erro,
            % indicando o que foi tentado, o que obtido de resposta do
            % comutador ect.
        end


        %-----------------------------------------------------------------%
        function newArray = RegularTask_specData(app, ii, jj, hReceiver, hStreaming)
            Timeout = 10;
            Flag_success = false;

            switch app.specObj(ii).taskObj.General.SCPI.connectFlag{1}
                case 1                                                      % Spectrum analyzers (R&S, KeySight, Tektronix, Anritsu)
                    recTic = tic;
                    t1 = toc(recTic);
                    while t1 < Timeout
                        try
                            writeline(hReceiver, app.specObj(ii).SCPI.scpiGet_Data);
                            newArray = readbinblock(hReceiver, 'single');
                                                        
                            if numel(newArray) == app.specObj(ii).Band(jj).DataPoints
                                if strcmp(app.specObj(ii).taskObj.Receiver.Sync, 'Continuous Sweep')
                                    SyncModeRef = sum(newArray);

                                    if SyncModeRef ~= app.specObj(ii).Band(jj).SyncModeRef
                                        app.specObj(ii).Band(jj).SyncModeRef = SyncModeRef;
                                    else
                                        continue
                                    end                                    
                                end

                                Flag_success = true;
                                break
                            end
    
                        catch
                        end
                        t1 = toc(recTic);
                    end
                    
                case 2                                                      % R&S EB500                    
                    taskInfo = struct('FreqStart',  app.specObj(ii).taskObj.General.Task.Band(jj).FreqStart, ...
                                      'FreqStop',   app.specObj(ii).taskObj.General.Task.Band(jj).FreqStop,  ...
                                      'DataPoints', app.specObj(ii).Band(jj).DataPoints,                     ...
                                      'nDatagrams', app.specObj(ii).Band(jj).Datagrams,                      ...
                                      'udpPort',    app.EB500Obj.udpPort);
                    [newArray, Flag_success] = class.EB500Lib.DatagramRead_Task(taskInfo, hReceiver, hStreaming);
            end
            flush(hReceiver)
            
            if Flag_success
                if app.specObj(ii).Band(jj).FlipArray
                    newArray = flip(newArray);
                end
            else
                error('Não foi lido corretamente o vetor de nível do receptor dentro do tempo limite (10 segundos).')
            end            
        end


        %-----------------------------------------------------------------%
        function PlotTask(app, ii, jj, newArray)
            Receiver   = app.specObj(ii).hReceiver.UserData.IDN;
            FreqStart  = app.specObj(ii).taskObj.General.Task.Band(jj).FreqStart / 1e+6;
            FreqStop   = app.specObj(ii).taskObj.General.Task.Band(jj).FreqStop  / 1e+6;
            DataPoints = app.specObj(ii).Band(jj).DataPoints;

            Tag = sprintf('%s; %.3f - %.3f MHz; %.0f points', Receiver, FreqStart, FreqStop, DataPoints);

%             TimeStamp = datetime('now');
%             app.specMatrix.Data{1}(ind)   = datetime('now');
%             app.specMatrix.Data{2}(ind,:) = newArray;
% 
%             app.specMatrix.SampleTime  = ((app.General.SampleTimeFactor-1)*app.specMatrix.SampleTime + str2double(datestr(app.specMatrix.Data{1}(indMatrix(end))-app.specMatrix.Data{1}(indMatrix(end-1)), 'ss.FFF'))*1000) / app.General.SampleTimeFactor;


            tempObj = findobj(app.axes1, 'Tag', Tag);
            if isempty(tempObj)
                plot(app.axes1, linspace(FreqStart, FreqStop, DataPoints), newArray, 'Tag', Tag);
            else
                tempObj.YData = newArray;
            end

            % PENDENTE ATUALIAÇÃO DO WATERFALL
        end


        %-----------------------------------------------------------------%
        function RegularTask_StartUp(app, idx)
            d = uiprogressdlg(app.UIFigure, 'Indeterminate', 'on', 'Interpreter', 'html', 'Message', 'Em andamento');

            taskObj  = app.specObj(idx).taskObj;
            baseName = sprintf('appColeta_%s', datestr(now, 'yymmdd_THHMMSS'));

            % HANDLES
            hReceiver  = app.specObj(idx).hReceiver;
            hStreaming = app.specObj(idx).hStreaming;
            hGPS       = app.specObj(idx).hGPS;

            % RECEIVER TEST
            flush(hReceiver)

            writeline(hReceiver, '*IDN?'); 
            pause(.1); 
            IDN = replace(deblank(read(hReceiver, hReceiver.NumBytesAvailable, 'char')), '"', '');
            
%             IDN = replace(deblank(writeread(hReceiver, '*IDN?')), '"', '');
            if ~strcmp(hReceiver.UserData.IDN, IDN)
                error('Não se trata do instrumento originalmente inserido na tarefa.\nEsperado: %s\nIdentificado: %s', hReceiver.UserData.IDN, IDN)
            end

            % RECEIVER TEST (DATAGRAMS ESTIMATION)
            if ~isempty(hStreaming)
                if contains(hReceiver.UserData.IDN, 'EB500') && isempty(app.specObj(idx).Band(1).Datagrams)
                    app.specObj(idx) = class.EB500Lib.DatagramRead_PreTask(app.EB500Obj, app.specObj(idx), hReceiver, hStreaming);
                end
            end

            % GPS TEST
            if ~isempty(hGPS)
                % TESTAR GPS EXTERNO
            end

            for ii = 1:numel(app.specObj(idx).Band)
                ThreadID = taskObj.General.Task.Band(ii).ThreadID;

                % ANTENNA SWITCH & ACU TESTS
                if strcmp(taskObj.Antenna.Switch, 'EMSat')
                    Antenna = jsondecode(app.specObj(idx).Band(ii).Antenna);
                    idxFlip = find(strcmp(app.EMSatObj.LNB.Name, Antenna.Name), 1);

                    app.specObj(idx).Band(ii).FlipArray = app.EMSatObj.LNB.Inverted(idxFlip);

%                     app.auxWin2 = winTracking(app, idx);
%                     drawnow
                end

                % MASK
                app.specObj(idx).Band(ii).Mask = [];
                if strcmp(taskObj.General.Type, 'Rompimento de Máscara Espectral') && taskObj.General.Task.Band(ii).Trigger
                    maskInfo = mask_FileRead(taskObj.General.Maskfile);

                    app.specObj(idx).Band(ii).Mask = struct('Array', mask_ArrayConstructor(maskInfo, taskObj.General.Task.Band(ii)), ...
                                                            'Validations', 0,                                                        ...
                                                            'BrokenArray', zeros(1, taskObj.General.Task.Band(ii).instrDataPoints),  ...
                                                            'BrokenCount', 0,                                                        ...
                                                            'MainPeaks', '',                                                         ...
                                                            'TimeStamp', NaT);
                    app.specObj(idx).LOG(end+1)    = struct('type', 'mask', 'time', datestr(now, 'dd/mm/yyyy HH:MM:SS'), 'msg', sprintf('ID %.0f\n%s', ThreadID, jsonencode(maskInfo.Table)));
                end


                % FILE
                app.specObj(idx).Band(ii).File = struct('Fileversion', app.General.File.Version,              ...
                                                        'Basename', sprintf('%s_ID%.0f', baseName, ThreadID), ...
                                                        'Filecount', 0, 'WritedSamples', 0, 'CurrentFile', []);

                [app.specObj(idx).Band(ii).File.Filecount, ...
                    app.specObj(idx).Band(ii).File.CurrentFile] = class.RFlookBinLib.OpenFile(app.specObj(idx), ii);

                logMsg = sprintf(['ID: %.0f\n'             ...
                                  'scpiSet_Config: "%s"\n' ...
                                  'scpiSet_Att: "%s"\n'    ...
                                  'scpiSet_Answer: "%s"\n' ...
                                  'Filename (base): %s\n'  ...
                                  'AlocatedSamples: %.0f'], ThreadID,                                 ...
                                                            app.specObj(idx).Band(ii).scpiSet_Config, ...
                                                            app.specObj(idx).Band(ii).scpiSet_Att,    ...
                                                            app.specObj(idx).Band(ii).scpiSet_Answer, ...
                                                            app.specObj(idx).Band(ii).File.Basename,  ...
                                                            app.specObj(idx).Band(ii).File.CurrentFile.AlocatedSamples);

                app.specObj(idx).LOG(end+1) = struct('type', 'startup', 'time', datestr(now, 'dd/mm/yyyy HH:MM:SS'), 'msg', logMsg);


                % WATERFALL MATRIX
                WaterfallDepth = app.General.Waterfall.Depth;   
                DataPoints     = taskObj.General.Task.Band(ii).instrDataPoints;

                switch taskObj.General.Task.Band(ii).instrLevelUnit
                    case 'dBm';            refLevel = -120;
                    case {'dBµV', 'dBμV'}; refLevel = -13;
                end
                
                if strcmp(taskObj.General.Task.Observation.Type, 'Samples')
                    WaterfallDepth  = min([WaterfallDepth,  taskObj.General.Task.Band(ii).instrObservationSamples]);
                end
                app.specObj(idx).Band(ii).Waterfall = struct('idx', 0, 'Matrix', refLevel .* ones(WaterfallDepth, DataPoints, 'single'));
            end
            delete(d)
        end


        %-----------------------------------------------------------------%
        function logMsg = Misc_logMsg(app, idx)
            logMsg = '';
            if ~isempty(app.specObj(idx).LOG)
                logTable = struct2table(app.specObj(idx).LOG);
                logMsg   = strjoin("<b>" + logTable.time + " - " + upper(logTable.type) + "</b>" + newline + logTable.msg, '\n\n');
            end
        end

    end

    
    methods (Access = public)
        %-----------------------------------------------------------------%
        function winAux_AddOrEditTask(app, taskObj)
            % Closing auxiliar window
            delete(app.auxiliarWin2);
            app.auxiliarWin2 = [];
            ccTools.fcn.modalWindowEffect(app.UIFigure, 'none')

            % Passing data to the object
            [app.specObj, idx] = Fcn_AddTask(app.specObj, taskObj);
            if ~isempty(app.specObj(idx).LOG)
                layoutFcn.modalWindow(app.UIFigure, 'ccTools.MessageBox', Misc_logMsg(app, idx));
            end

            % Startup of every task
            timer_RegularTaskCheck(app)
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.RootFolder = fileparts(mfilename('fullpath'));

            try
                % <LOG>
                diary(fullfile(app.RootFolder, 'Debug', '_log.txt'))                
                diary on
                fprintf(sprintf('<LogEntry>\n<BeginTime>%s</BeginTime>\n', datestr(now,'dd/mm/yyyy HH:MM:SS')))
                % </LOG>

                warning('off', 'MATLAB:ui:javaframe:PropertyToBeRemoved')
                warning('off', 'MATLAB:subscripting:noSubscriptsSpecified')
                warning('off', 'MATLAB:structOnObject')
                warning('off', 'MATLAB:class:DestructorError')

                startup_WindowPosition(app)
                startup_AxesCreation(app)
                startup_ConfigFiles(app)
                startup_TimerCreation(app)

            catch ME
                fprintf('%s\n', jsonencode(ME))
                layoutFcn.modalWindow(app.UIFigure, 'error', getReport(ME), 'CloseFcn', @(~,~)closeFcn(app));
            end
            drawnow
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            if app.Flag_running
                layoutFcn.modalWindow(app.UIFigure, "ccTools.MessageBox", "Existe uma tarefa em execução...");
                return
            end
            
            % <LOG>
            fprintf(sprintf('<EndTime>%s</EndTime>\n</LogEntry>\n', datestr(now,'dd/mm/yyyy HH:MM:SS')))
            diary off
            
            log = fileread(fullfile(app.RootFolder, 'Debug', '_log.txt'));
            if contains(log, 'error', 'IgnoreCase', true)
                writematrix(log, fullfile(app.RootFolder, 'Debug', '_error.txt'), "QuoteStrings", false, "WriteMode", "append")
                writematrix("",  fullfile(app.RootFolder, 'Debug', '_log.txt'),   "QuoteStrings", false, "WriteMode", "overwrite")
            end
            % </LOG>

            h = timerfindall;
            stop(h); delete(h); clear h

            delete(app.auxiliarWin1)
            delete(app.auxiliarWin2)
            delete(app)
            
        end

        % Callback function: Table
        function TableSelectionChanged(app, event)

            layoutFcn.treeBuilding(app, 1)
            TreeSelectionChanged(app)
            
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)

            cla(app.axes1)
            cla(app.axes2)

            ii = app.Table.Selection;
            jj = app.Tree.SelectedNodes.NodeData;

            if ~isempty(app.specObj(ii).Band(jj).Waterfall)
                idx = app.specObj(ii).Band(jj).Waterfall.idx;

                if idx
                    newArray = app.specObj(ii).Band(jj).Waterfall.Matrix(idx,:);
                    PlotTask(app, ii, jj, newArray)
                end
            end

            % INFORMAÇÕES DA TAREFA QUE SÃO ATUALIZADAS EM TEMPO REAL
            % (LAYOUT)
            Task = app.specObj(ii).taskObj.General.Task;

            sRevisitTime = sprintf('%.2f seg', Task.Band(jj).RevisitTime);
            try
                if app.revisitObj.Band(ii).RevisitFactors(jj+1) ~= -1
                    sRevisitTime = sprintf('%.2f → %.2f seg (normalizado)', Task.Band(jj).RevisitTime, ...
                                                                            app.revisitObj.GlobalRevisitTime * app.revisitObj.Band(ii).RevisitFactors(jj+1));
                end
            catch
            end

            app.MetaData.HTMLSource = sprintf(['<font style="font-family: Helvetica; font-size: 10px;">'  ...
                                               '<b style="color: #808080;">DESCRIÇÃO: </b>%s<br><br>',    ...
                                               '<b style="color: #808080;">PASSO: </b>%.3f kHz<br>',      ...
                                               '<b style="color: #808080;">PONTOS: </b>%.0f<br>',         ...
                                               '<b style="color: #808080;">RESOLUÇÃO: </b>%s<br>',        ...
                                               '<b style="color: #808080;">DETECTOR: </b>%s<br><br>',     ...
                                               '<b style="color: #808080;">TRAÇO: </b>%s<br>',            ...
                                               '<b style="color: #808080;">INTEGRAÇÃO: </b>%.0f<br><br>', ...
                                               '<b style="color: #808080;">REVISITA: </b>%s</font>'],     ...
                                               Task.Band(jj).Description,           ...
                                               Task.Band(jj).instrStepWidth / 1e+3, ...
                                               Task.Band(jj).instrDataPoints,       ...
                                               Task.Band(jj).instrResolution,       ...
                                               Task.Band(jj).instrDetector,         ...
                                               Task.Band(jj).TraceMode,             ...
                                               Task.Band(jj).IntegrationFactor,     ...                                               
                                               sRevisitTime);

            if ~isempty(app.specObj(ii).Band(jj).File); WritedSamples = app.specObj(ii).Band(jj).File.WritedSamples;
            else;                                       WritedSamples = -1; 
            end
            app.Channel.Text = string(WritedSamples);

            if strcmp(app.specObj(ii).Status, 'Em andamento') && app.specObj(ii).Band(jj).Status
                app.tool_REC.Visible = 1;
            else
                app.tool_REC.Visible = 0;
            end

            if ~isempty(app.specObj(ii).Band(jj).Mask)
                app.lastMask_text.Enable = 1;
                layoutFcn.lastMaskValidation(app, true, ii, jj)
            else
                app.lastMask_text.Enable = 0;
                app.lastMask_text.Text = {'<b style="color: #a2142f; font-size: 14;">-1</b> '; 'VALIDAÇÕES '; '<b style="color: #a2142f; font-size: 14;">-1</b> '; 'ROMPIMENTOS '; ''; '<font style="color: #a2142f;">-1.000 MHz '; '⌂ -1.000 kHz </font>'; ''; 'dd/mm/yyyy '; 'HH:MM:SS '};
            end
            drawnow
            
            layoutFcn.lastGPS(app, app.specObj(ii).lastGPS)            
            
        end

        % Button pushed function: Button_Del, Button_Edit, Button_New, 
        % ...and 3 other components
        function ButtonPushed_taskTool(app, event)
            
            switch event.Source
                case app.Button_Play
                    if ~isempty(app.specObj)
                        RegularTask_MainLoop(app)
                    end

                case app.Button_Stop
                    app.Flag_running = 0;                    

                case {app.Button_New, app.Button_Edit}
                        d = layoutFcn.modalWindow(app.UIFigure, "ccTools.ProgressDialog");
                        
                        switch event.Source
                            case app.Button_New;  tempVar = struct('type', 'new');
                            case app.Button_Edit; tempVar = struct('type', 'edit', 'idx', app.Table.Selection);
                        end

                        app.auxiliarWin2 = auxApp.winAddTask(app, tempVar);
                        drawnow

                        delete(d)

                case app.Button_Del
                    idx = app.Table.Selection;

                    if ismember(app.specObj(idx).Status, ["Na fila", "Em andamento", "Erro"])
                        if strcmp(app.specObj(idx).Status , 'Em andamento')
                            app.Flag_editing = 1;
                        end
                        app.specObj(idx).Status = "Cancelada";
                    end
            end

        end

        % Button pushed function: Button_LayoutLeft, Button_LayoutRight, 
        % ...and 1 other component
        function ButtonPushed_layoutTool(app, event)
            switch event.Source
                case app.Button_LayoutTop
                    if app.GridLayout.RowHeight{1}
                        app.GridLayout.RowHeight{1} = 0;
                    else
                        app.GridLayout.RowHeight{1} = 200;
                    end

                case app.Button_LayoutLeft
                    if app.GridLayout.ColumnWidth{1}
                        app.GridLayout.ColumnWidth{1} = 0;
                    else
                        app.GridLayout.ColumnWidth{1} = 256;
                    end

                case app.Button_LayoutRight
                    if app.GridLayout.ColumnWidth{5}
                        app.GridLayout.ColumnWidth{5} = 0;
                    else
                        app.GridLayout.ColumnWidth{5} = 120;
                    end
            end
        end

        % Button pushed function: Button_Settings
        function ButtonPushed_settingsTool(app, event)
            if isempty(app.auxiliarWin1)
                d = layoutFcn.modalWindow(app.UIFigure, "ccTools.ProgressDialog");

                app.auxiliarWin1 = auxApp.winSettings(app);
                drawnow

                delete(d)                
            else
                figure(app.auxiliarWin1.UIFigure)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appColeta: Tarefas';
            app.UIFigure.Icon = 'LR_icon.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.GridLayout9 = uigridlayout(app.UIFigure);
            app.GridLayout = uigridlayout(app.GridLayout9);
            app.Plot_Panel = uipanel(app.GridLayout);
            app.FaixasdefrequnciarelacionadastarefaselecionadaLabel = uilabel(app.GridLayout);
            app.FaixasdefrequnciarelacionadastarefaselecionadaLabel.Text = 'Faixa(s) de frequência relacionada(s) à tarefa selecionada:';
            app.Tree = uitree(app.GridLayout);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.Panel_5 = uipanel(app.GridLayout3);
            app.GridLayout2_2 = uigridlayout(app.Panel_5);
            app.GridLayout2_2.Tag = 'COLORLOCKED';
            app.tool_REC = uiimage(app.GridLayout2_2);
            app.tool_REC.Visible = 'off';
            app.tool_REC.ImageSource = 'REC.gif';
            app.ChannelLabel = uilabel(app.GridLayout2_2);
            app.ChannelLabel.Text = {'VARREDURAS'; 'EM ARQUIVO'};
            app.Channel = uilabel(app.GridLayout2_2);
            app.Channel.Text = '-1';
            app.Panel_6 = uipanel(app.GridLayout3);
            app.GridLayout2_3 = uigridlayout(app.Panel_6);
            app.GridLayout2_3.Tag = 'COLORLOCKED';
            app.lastGPS_label = uilabel(app.GridLayout2_3);
            app.lastGPS_label.Text = {'COORDENADAS'; 'GEOGRÁFICAS'};
            app.lastGPS_text = uilabel(app.GridLayout2_3);
            app.lastGPS_text.Interpreter = 'html';
            app.lastGPS_text.Text = {'<b style="color: #a2142f; font-size: 14;">-1.000</b> LAT '; '<b style="color: #a2142f; font-size: 14;">-1.000</b> LON '; ''; 'dd/mm/yyyy '; 'HH:MM:SS '};
            app.GridLayout7 = uigridlayout(app.GridLayout2_3);
            app.lastGPS_color = uilamp(app.GridLayout7);
            app.Panel_7 = uipanel(app.GridLayout3);
            app.GridLayout2_6 = uigridlayout(app.Panel_7);
            app.GridLayout2_6.Tag = 'COLORLOCKED';
            app.lastMask_label = uilabel(app.GridLayout2_6);
            app.lastMask_label.Text = {'MÁSCARA'; 'ESPECTRAL'};
            app.lastMask_text = uilabel(app.GridLayout2_6);
            app.lastMask_text.Interpreter = 'html';
            app.lastMask_text.Enable = 'off';
            app.lastMask_text.Text = {'<b style="color: #a2142f; font-size: 14;">-1</b> '; 'VALIDAÇÕES '; '<b style="color: #a2142f; font-size: 14;">-1</b> '; 'ROMPIMENTOS '; ''; '<font style="color: #a2142f;">-1.000 MHz '; '⌂ -1.000 kHz </font>'; ''; 'dd/mm/yyyy '; 'HH:MM:SS '};
            app.MetadadosrelacionadosfaixaselecionadaLabel = uilabel(app.GridLayout);
            app.MetadadosrelacionadosfaixaselecionadaLabel.Text = 'Metadados relacionados à faixa selecionada:';
            app.MetaData_Panel = uipanel(app.GridLayout);
            app.MetaData_Grid = uigridlayout(app.MetaData_Panel);
            app.MetaData = uihtml(app.MetaData_Grid);
            app.MetaData.HTMLSource = ' ';
            app.Table = ccTools.Table(app.GridLayout);
            app.Table.ColumnName = {'ID', 'Tarefa', 'Receptor', 'Inclusão', 'Início|Observação', 'Fim|Observação', 'Estado'};
            app.Table.hFontSize = 11;
            app.Table.hClickable = false;
            app.Table.SelectionChangedFcn = createCallbackFcn(app, @TableSelectionChanged, true);
            app.GridLayout8 = uigridlayout(app.GridLayout9);
            app.Button_Play = uibutton(app.GridLayout8, 'push');
            app.Button_Play.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_taskTool, true);
            app.Button_Play.Icon = fullfile(pathToMLAPP, 'Icons', 'DT_play.png');
            app.Button_Play.Text = '';
            app.Button_Stop = uibutton(app.GridLayout8, 'push');
            app.Button_Stop.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_taskTool, true);
            app.Button_Stop.Icon = fullfile(pathToMLAPP, 'Icons', 'DT_stop.png');
            app.Button_Stop.Text = '';
            app.Button_Edit = uibutton(app.GridLayout8, 'push');
            app.Button_Edit.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_taskTool, true);
            app.Button_Edit.Icon = fullfile(pathToMLAPP, 'Icons', 'DT_edit.png');
            app.Button_Edit.Text = '';
            app.Button_Del = uibutton(app.GridLayout8, 'push');
            app.Button_Del.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_taskTool, true);
            app.Button_Del.Icon = fullfile(pathToMLAPP, 'Icons', 'DT_X.png');
            app.Button_Del.Text = '';
            app.Button_log = uibutton(app.GridLayout8, 'push');
            app.Button_log.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_taskTool, true);
            app.Button_log.Icon = fullfile(pathToMLAPP, 'Icons', 'DT_log.png');
            app.Button_log.Text = '';
            app.Separator1 = uiimage(app.GridLayout8);
            app.Separator1.Enable = 'off';
            app.Separator1.ImageSource = fullfile(pathToMLAPP, 'Icons', 'DT_LineV.png');
            app.Separator2 = uiimage(app.GridLayout8);
            app.Separator2.Enable = 'off';
            app.Separator2.ImageSource = fullfile(pathToMLAPP, 'Icons', 'DT_LineV.png');
            app.Status = uilabel(app.GridLayout8);
            app.Status.Text = 'BANDA 54.000 - 88.000 MHz';
            app.Button_LayoutTop = uibutton(app.GridLayout8, 'push');
            app.Button_LayoutTop.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_layoutTool, true);
            app.Button_LayoutTop.Icon = fullfile(pathToMLAPP, 'Icons', 'layoutUp.png');
            app.Button_LayoutTop.Text = '';
            app.Button_LayoutLeft = uibutton(app.GridLayout8, 'push');
            app.Button_LayoutLeft.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_layoutTool, true);
            app.Button_LayoutLeft.Icon = fullfile(pathToMLAPP, 'Icons', 'layoutLeft.png');
            app.Button_LayoutLeft.Text = '';
            app.Button_LayoutRight = uibutton(app.GridLayout8, 'push');
            app.Button_LayoutRight.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_layoutTool, true);
            app.Button_LayoutRight.Icon = fullfile(pathToMLAPP, 'Icons', 'layoutRight.png');
            app.Button_LayoutRight.Text = '';
            app.Separator3 = uiimage(app.GridLayout8);
            app.Separator3.Enable = 'off';
            app.Separator3.ImageSource = fullfile(pathToMLAPP, 'Icons', 'DT_LineV.png');
            app.Button_New = uibutton(app.GridLayout8, 'push');
            app.Button_New.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_taskTool, true);
            app.Button_New.Icon = fullfile(pathToMLAPP, 'Icons', 'DT_AddFiles.png');
            app.Button_New.Text = '';
            app.Separator4 = uiimage(app.GridLayout8);
            app.Separator4.Enable = 'off';
            app.Separator4.ImageSource = fullfile(pathToMLAPP, 'Icons', 'DT_LineV.png');
            app.Button_Settings = uibutton(app.GridLayout8, 'push');
            app.Button_Settings.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_settingsTool, true);
            app.Button_Settings.Icon = fullfile(pathToMLAPP, 'Icons', 'DT_settings.png');
            app.Button_Settings.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winAppColetaV2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end