classdef winMain < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        Tree                 matlab.ui.container.Tree
        FaixasdefrequnciarelacionadastarefaselecionadaLabel  matlab.ui.control.Label
        GridLayout3          matlab.ui.container.GridLayout
        Image6               matlab.ui.control.Image
        task_NameEdit_2      matlab.ui.control.Button
        task_NameEdit        matlab.ui.control.Button
        task_scpiListEdit_2  matlab.ui.control.Button
        Image5               matlab.ui.control.Image
        Image2               matlab.ui.control.Image
        Image                matlab.ui.control.Image
        Image3               matlab.ui.control.Image
        Image4               matlab.ui.control.Image
        task_scpiListEdit    matlab.ui.control.Button
        Panel                matlab.ui.container.Panel
        table_Grid           matlab.ui.container.GridLayout
        Column5_2            matlab.ui.control.Label
        Column5              matlab.ui.control.Label
        Export_Grid          matlab.ui.container.GridLayout
        table_Separator      matlab.ui.control.Image
        table_Play           matlab.ui.control.Image
        table_Stop           matlab.ui.control.Image
        table_Edit           matlab.ui.control.Image
        table_New            matlab.ui.control.Image
        table_Del            matlab.ui.control.Image
        Column10             matlab.ui.control.Label
        Column8              matlab.ui.control.Label
        Column7              matlab.ui.control.Label
        Column3              matlab.ui.control.Label
        Column2              matlab.ui.control.Label
        Column1              matlab.ui.control.Label
        UITable              matlab.ui.control.Table
    end

    
    properties (Access = public)
        
        General
        RootFolder

        axes1
        axes2

        specObj      = specClass
        timeObj

        instrHandles = table('Size', [0, 5],                                                    ...
                             'VariableTypes', {'string', 'string', 'string', 'cell', 'double'}, ...
                             'VariableNames', {'Type', 'IDN', 'Socket', 'Handle', 'Status'})
        udpPortArray = {}
        
        taskList
        scpiList
        instrInfo

        Flag_running
        Flag_editing

        auxWin1 = []
        auxWin2 = []
        auxWin3 = []

        d

    end

    
    methods (Access = private)

        function startup_Layout(app)

            mainMonitor = get(0, 'MonitorPositions');

            [~, ind]    = max(mainMonitor(:,3));
            mainMonitor = mainMonitor(ind,:);
            
            app.UIFigure.Position(1:2) = [mainMonitor(1)+round((mainMonitor(3)-app.UIFigure.Position(3))/2), ...
                                          mainMonitor(2)+round((mainMonitor(4)-app.UIFigure.Position(4)-30)/2)];

        end


        function startup_AxesCreation(app)

            t = tiledlayout(app.Panel, 3, 1, "Padding", "tight", "TileSpacing", "compact");

            % app.UIAxes1
            app.axes1 = uiaxes(t);
            app.axes1.Layout.Tile = 1;
            set(app.axes1, Color = [0.94, .94, .94], ...
                           FontSize = 8)

            % app.UIAxes2
            app.axes2 = uiaxes(t);
            app.axes2.Layout.Tile = 2;
            app.axes2.Layout.TileSpan  = [2,1];
            set(app.axes2, Color = [0.94, .94, .94], ...
                           FontSize = 8)

        end


        function startup_ConfigFiles(app)

            % app.General
            tempObj = jsondecode(fileread(fullfile(app.RootFolder, 'Settings', 'GeneralSettings.json')));
            tempObj.layout.lightColor = tempObj.layout.lightColor';
            tempObj.layout.cyanColor  = tempObj.layout.cyanColor';

            app.General = tempObj;


            % app.taskList
            taskTemp = jsondecode(fileread('taskList.json'));
            for ii = 1:numel(taskTemp)
                app.taskList{ii} = taskTemp(ii);
    
                if (app.taskList{ii}.Observation.Type == "Duration") & isempty(app.taskList{ii}.Observation.Duration)
                    app.taskList{ii}.Observation.Duration = inf;
                end
    
                if ~any([app.taskList{ii}.Band.Enable])
                    app.taskList{ii}.Band(1).Enable = 1;
                end
            end


            % app.scpiList
            tempList = jsondecode(fileread('scpiList.json'));
            for ii = 1:numel(tempList)
                tempList(ii).Parameters = jsonencode(tempList(ii).Parameters);
            end

            app.scpiList     = struct2table(tempList, 'AsArray', true);
            app.scpiList.idx = (1:numel(tempList))';
            app.scpiList     = movevars(app.scpiList, 'idx', 'Before', 1);


            % app.instrInfo            
            app.instrInfo = struct2table(jsondecode(fileread(fullfile(app.RootFolder, 'Settings', 'instrInfo.json'))));

        end


        function MessageBox(app, type, msg)

            appName = 'appColeta';

            msg = sprintf('<font style="font-size:12;">%s</font>', msg);
            switch type
                case 'error';   uialert(app.UIFigure, msg, appName, 'Interpreter', 'html');
                case 'warning'; uialert(app.UIFigure, msg, appName, 'Interpreter', 'html', 'Icon', 'warning');
                case 'info';    uialert(app.UIFigure, msg, appName, 'Interpreter', 'html', 'Icon', 'LT_info.png')
                case 'startup'; app.UIFigure.Visible = 1; uialert(app.UIFigure, msg, appName, 'Interpreter', 'html', 'CloseFcn', @(~,~)closeFcn(app));
            end

        end


        function Fcn_TableBuilding(app, idx)

            tempTable = table('Size', [0, 8],                                                                                    ...
                              'VariableTypes', {'double', 'string', 'string', 'string', 'string', 'string', 'string', 'string'}, ...
                              'VariableNames', {'ID', 'Name', 'Created', 'BeginTime', 'EndTime', 'Status', 'Receiver', 'Recursos'});

            
            for ii = 1:numel(app.specObj)
                Receiver = app.specObj(ii).hReceiver.Address;
                try
                    Receiver = sprintf('%s:%.0f', Receiver, app.specObj(ii).hReceiver.Port);
                catch
                end

                GPS     = app.specObj(ii).taskObj.General.Task.GPS.Type;
                Switch  = app.specObj(ii).taskObj.Antenna.Switch.Name;
                Antenna = strjoin(unique({app.specObj(ii).Band.Antenna}), '\n');

                Resources = struct('Receiver', Receiver, ...
                                   'GPS',      GPS,      ...
                                   'Switch',   Switch,   ...
                                   'Antenna',  Antenna);

                EndTime = '-';
                if ~isnat(app.specObj(ii).Observation.EndTime)
                    EndTime = datestr(app.specObj(ii).Observation.EndTime, 'dd/mm/yyyy HH:MM:SS');
                end

                tempTable(end+1,:) = {app.specObj(ii).ID,                        ...
                                      app.specObj(ii).taskObj.General.Task.Name, ...
                                      app.specObj(ii).Observation.Created,       ...
                                      datestr(app.specObj(ii).Observation.BeginTime, 'dd/mm/yyyy HH:MM:SS'), ...
                                      EndTime,                                   ...
                                      app.specObj(ii).Status,                    ...
                                      app.specObj(ii).hReceiver.UserData.IDN,    ...
                                      jsonencode(Resources)};
            end

            app.UITable.Data      = tempTable;
            app.UITable.Selection = idx;

            UITableSelectionChanged(app)

        end


        function Fcn_TreeBuilding(app)
            
            delete(app.Tree.Children);
            
            idx = app.UITable.Selection;
            for ii = 1:numel(app.specObj(idx).taskObj.General.Task.Band)
                Antenna = app.specObj(idx).taskObj.General.Task.Band.instrAntenna;
                if ~isempty(Antenna)
                    AntennaName = sprintf('(%s)', Antenna);
                end

                uitreenode(app.Tree, 'Text', sprintf('ID %d: %.3f - %.3f MHz %s',                                      ...
                                                     app.specObj(idx).taskObj.General.Task.Band(ii).ThreadID,          ...
                                                     app.specObj(idx).taskObj.General.Task.Band(ii).FreqStart .* 1e-6, ...
                                                     app.specObj(idx).taskObj.General.Task.Band(ii).FreqStop  .* 1e-6, ...
                                                     Antenna),                                                         ...
                                     'NodeData', ii);
            end
            
            app.Tree.SelectedNodes = app.Tree.Children(1);
            TreeSelectionChanged(app)
            
        end


        function task_ParametersEditables(app, idx1, idx2)

            for ii = 1:numel(app.taskList{idx2}.Band)
                task_ParametersEditables_SaveValues(app, idx1, idx2, ii)
            end

        end


        function task_ParametersEditables_SaveValues(app, idx1, idx2, idx3)

            instrSettings = task_ParametersEditables_GetValues(app, idx1, idx2, idx3);

            app.taskList{idx2}.Band(idx3).instrStepWidth_Items   = instrSettings.StepWidth_Items;
            app.taskList{idx2}.Band(idx3).instrStepWidth         = instrSettings.StepWidth;

            app.taskList{idx2}.Band(idx3).instrDataPoints_Limits = instrSettings.DataPoints_Limits;
            app.taskList{idx2}.Band(idx3).instrDataPoints        = instrSettings.DataPoints;

            app.taskList{idx2}.Band(idx3).instrResolution_Items  = instrSettings.Resolution_Items;
            app.taskList{idx2}.Band(idx3).instrResolution        = instrSettings.Resolution;
            
            app.taskList{idx2}.Band(idx3).instrSelectivity       = instrSettings.Selectivity;
            app.taskList{idx2}.Band(idx3).instrSensitivityMode   = instrSettings.SensitivityMode;
            
            app.taskList{idx2}.Band(idx3).instrPreamp            = instrSettings.Preamp;
            app.taskList{idx2}.Band(idx3).instrAttMode           = instrSettings.AttMode;
            app.taskList{idx2}.Band(idx3).instrAttFactor_Items   = instrSettings.AttFactor_Items;
            app.taskList{idx2}.Band(idx3).instrAttFactor         = instrSettings.AttFactor;

            app.taskList{idx2}.Band(idx3).instrDetector_Items    = instrSettings.Detector_Items;
            indDetector = find(strcmp(app.taskList{idx2}.Band(idx3).instrDetector_Items, app.taskList{idx2}.Band(idx3).Detector), 1);
            if isempty(indDetector)
                indDetector = 1;
            end
            app.taskList{idx2}.Band(idx3).instrDetector          = instrSettings.Detector_Items{indDetector};
            app.taskList{idx2}.Band(idx3).instrLevelUnit         = instrSettings.LevelUnit;
            app.taskList{idx2}.Band(idx3).instrIntegrationTime   = instrSettings.IntegrationTime;

            app.taskList{idx2}.Band(idx3).EditedFlag             = 0;

        end


        function instrSettings = task_ParametersEditables_GetValues(app, idx1, idx2, idx3)

            instrSettings = struct('StepWidth_Items',   [], 'StepWidth',       [], ...
                                   'DataPoints_Limits', [], 'DataPoints',      [], ...
                                   'Resolution_Items',  [], 'Resolution',      [], ...
                                   'Selectivity_Items', [], 'Selectivity',     [], ...
                                   'SensitivityMode',   [], 'Preamp',          [], ...
                                   'AttMode',           [], 'AttFactor_Items', [], ...
                                   'AttFactor',         [], 'LevelUnit',       [], ...
                                   'Detector_Items',    [], 'IntegrationTime', []);

            span = app.taskList{idx2}.Band(idx3).FreqStop - app.taskList{idx2}.Band(idx3).FreqStart;
            
            instrSettings.AttMode         = 'Auto';
            instrSettings.AttFactor_Items = strsplit(app.instrInfo.Attenuation_Values{idx1}, ',');
            instrSettings.AttFactor       = instrSettings.AttFactor_Items{1};
            instrSettings.Detector_Items  = strsplit(app.instrInfo.Detector_Items{idx1}, ',');

            switch app.instrInfo.connectFlag{idx1}
                case 1
                    instrSettings.Resolution_Items = strsplit(app.instrInfo.Resolution_Values{idx1}, ',');

                    rbwValues = [];
                    for ii = 1:numel(instrSettings.Resolution_Items)
                        rbwValues = [rbwValues, str2double(extractBefore(instrSettings.Resolution_Items{ii}, ' kHz')).*1000];
                    end

                    instrSettings.DataPoints_Limits = app.instrInfo.DataPoints_Limits{idx1};

                    DataPoints = round(span/app.taskList{idx2}.Band(idx3).StepWidth + 1);
                    if     DataPoints < instrSettings.DataPoints_Limits(1); instrSettings.DataPoints = instrSettings.DataPoints_Limits(1);
                    elseif DataPoints > instrSettings.DataPoints_Limits(2); instrSettings.DataPoints = fix(instrSettings.DataPoints_Limits(2));
                    else;                                                   instrSettings.DataPoints = DataPoints;
                    end
                    instrSettings.StepWidth = span/(instrSettings.DataPoints - 1);

                    rbwIndex  = find(abs(rbwValues - app.taskList{idx2}.Band(idx3).Resolution) == min(abs(rbwValues - app.taskList{idx2}.Band(idx3).Resolution)));
                    instrSettings.Resolution = instrSettings.Resolution_Items{rbwIndex};

                    instrSettings.Selectivity     = '';

                    instrSettings.SensitivityMode = '0';
                    instrSettings.Preamp          = 'On';

                    instrSettings.LevelUnit       = app.taskList{idx2}.Band(idx3).LevelUnit;

                    switch app.taskList{idx2}.Band(idx3).RFMode
                        case 'High Sensitivity'
                            instrSettings.AttMode         = 'Manual';
                            instrSettings.SensitivityMode = '1';
                        case 'Low Distortion'
                            instrSettings.Preamp          = 'Off';
                    end

                case 2                                                                                              % EB500
                    instrSettings.StepWidth_Items   = strsplit(app.instrInfo.StepWidth_Values{idx1}, ',');
                    instrSettings.Selectivity_Items = {'Normal', 'Narrow', 'Sharp'};

                    stepValues = [];
                    for ii = 1:length(instrSettings.StepWidth_Items)
                        stepValues = [stepValues, str2double(extractBefore(instrSettings.StepWidth_Items{ii}, ' kHz')).*1000];
                    end

                    stepIndex  = find(abs(stepValues - app.taskList{idx2}.Band(idx3).StepWidth) == min(abs(stepValues - app.taskList{idx2}.Band(idx3).StepWidth)));
                    instrSettings.StepWidth  = instrSettings.StepWidth_Items{stepIndex};

                    instrSettings.DataPoints = span/(str2double(extractBefore(instrSettings.StepWidth, ' kHz'))*1000) + 1;

                    rbwValues = [];
                    rbwItems  = {};
                    for ii = 1:3
                        rbwValues = [rbwValues, app.EB500Map{instrSettings.StepWidth, ii}];
                        rbwItems  = [rbwItems,  sprintf('%.3f kHz', rbwValues(ii)/1000)];
                    end
                    instrSettings.Resolution_Items = rbwItems;

                    rbwIndex = find(abs(rbwValues - app.taskList{idx2}.Band(idx3).Resolution) == min(abs(rbwValues - app.taskList{idx2}.Band(idx3).Resolution)));
                    instrSettings.Resolution  = instrSettings.Resolution_Items{rbwIndex};
                    instrSettings.Selectivity = app.task_Selectivity.Items{rbwIndex};

                    instrSettings.SensitivityMode = 'NORM';
                    instrSettings.Preamp          = 'Off';
                    instrSettings.LevelUnit       = 'dBμV';

                    switch app.taskList{idx2}.Band(idx3).RFMode
                        case 'High Sensitivity'
                            instrSettings.AttMode = 'Manual';
                        case 'Low Distortion'
                            instrSettings.SensitivityMode = 'LOWD';
                    end

                    switch app.taskList{idx2}.Band(idx3).TraceMode
                        case 'ClearWrite'
                            instrSettings.IntegrationTime = 0;
                        otherwise
                            instrSettings.IntegrationTime = 10 * app.taskList{idx2}.Band(idx3).IntegrationFactor;
                    end
            end

        end


        function task_TableToolbarCallbacks(app, event)

            switch event.Source
                case app.table_Play
                    % Pendente

                case app.table_Stop
                    % Pendente

                case app.table_New
                    % Pendente

                case app.table_Open
                    % Pendente

                case app.table_Del
                    % Pendente
            end

        end


        function gpsData = RegularTask_gpsData(app, idx, hReceiver, hGPS)

            gpsData = struct('Status', 0, 'Latitude', -1, 'Longitude', -1);

            try
                switch app.specObj(idx).taskObj.General.Task.GPS.Type
                    case 'Built-in'; gpsData = task_gpsDecode(hReceiver);
                    case 'External'; gpsData = task_gpsReader(hGPS, 1);
                end
            catch
            end

        end


        function newArray = RegularTask_specData(app, ii, jj, hReceiver, hStreaming)
            Timeout = 10;
            Flag_success = false;

            recTic = tic;
            t = toc(recTic);        
            
            switch app.specObj(ii).taskObj.General.SCPI.connectFlag
                case 1                                                      % Spectrum analyzers
                    while t < Timeout
                        try
                            writeline(hReceiver, app.specObj(idx).SCPI.scpiGet_Data);
                            newArray = readbinblock(hReceiver, 'single');
                            
                            SyncModeRef = sum(newArray);
                            if (numel(newArray) == app.specObj(ii).Band(jj).DataPoints) & (SyncModeRef ~= app.specObj(ii).Band(jj).SyncModeRef)
                                app.specObj(ii).Band(jj).SyncModeRef = SyncModeRef;
                                Flag_success = true;
                                break
                            end
    
                        catch
                        end
                        t = toc(recTic);
                    end
                    
                case 2                                                      % R&S EB500
                    lastwarn('')
        
                    newArray = zeros(1, app.specObj(ii).Band(jj).DataPoints, 'single');
                    RegularTask_EB500_udpConnection(app, 'Open')
        
                    while t < Timeout
                        nDatagrams   = 2*app.specObj(ii).Band(jj).Datagrams-1;
                        specDatagram = read(hStreaming, nDatagrams);
        
                        [warnMsg, warnID] = lastwarn;
                        if strcmp(warnID, 'instrument:interface:udpport:ReadWarning')
                            error(struct('message', warnMsg, 'identifier', warnID))
                        end

                        % Sorted datagrams
                        DatagramsID = zeros(numel(specDatagram), 1);        
                        for kk = 1:numel(specDatagram)
                            specDatagram(kk).Data = uint8(specDatagram(kk).Data);
        
                            DatagramID_low  = double(typecast(specDatagram(kk).Data(10:-1: 9), 'uint16'));
                            DatagramID_high = double(typecast(specDatagram(kk).Data(12:-1:11), 'uint16')).*2^16;
                            DatagramsID(kk) = DatagramID_low + DatagramID_high;
                        end

                        if ~issorted(DatagramsID)
                            [DatagramsID, idxSort] = sort(DatagramsID);
                            specDatagram = specDatagram(idxSort);
                        end

                        % Array
                        udpFlag = 0;
                        Points  = 0;

                        for kk = 1:numel(specDatagram)
                            if udpFlag & (DatagramsID(kk) ~= DatagramsID(kk-1)+1)
                                udpFlag = 0;
                                Points  = 0;
                                continue
                            end

                            MagicNumber    = typecast(specDatagram(kk).Data( 4:-1: 1), 'uint32');
                            DataSize       = typecast(specDatagram(kk).Data(16:-1:13), 'uint32');
                            FreqStart_low  = double(typecast(specDatagram(kk).Data(32:-1:29), 'uint32'));
                            FreqStart_high = double(typecast(specDatagram(kk).Data(44:-1:41), 'uint32')).*2^32;        
                            FreqStart      = FreqStart_low + FreqStart_high;
        
                            if (MagicNumber == 963072) & (DataSize == numel(specDatagram(kk).Data)) & (FreqStart == app.specObj(ii).taskObj.General.Task.Band(jj).FreqStart)
                                Terminator = typecast(specDatagram(kk).Data(end:-1:end-1), 'uint16');
            
                                idx1   = Points + 1;
                                Points = Points + typecast(specDatagram(kk).Data(22:-1:21), 'uint16');
                                idx2   = Points;
            
                                if Terminator == 2000
                                    if Points == app.specObj(ii).Band(jj).DataPoints+1
                                        newArray(idx1:idx2-1) = single(flip(typecast(specDatagram(kk).Data((end-2):-1:81),'int16')))./10;
                                        Flag_success = true;
                                        break
                                    else
                                        udpFlag = 0;
                                        Points  = 0;
                                    end
                                else
                                    udpFlag = 1;
                                    newArray(idx1:idx2) = single(flip(typecast(specDatagram(kk).Data(end:-1:81),'int16')))./10;
                                end
                            end
                        end

                        if Flag_success
                            break
                        end
                        t = toc(recTic);
                    end
                    RegularTask_EB500_udpConnection(app, 'Close')
            end
            flush(hReceiver)
            
            if ~Flag_success
                error('Não foi lido corretamente o vetor de nível do receptor dentro do tempo limite (10 segundos).')
            end            
        end


        function RegularTask_EB500_udpConnection(app, Type, hReceiver, hStreaming)
            Localhost_publicIP = hReceiver.UserData.ClientIP;

            switch Type
                case 'Open'
                    flush(hStreaming)

                    writeline(hReceiver, sprintf('TRACE:UDP:TAG:ON "%s", %.0f, PSCAN',                Localhost_publicIP, app.General.EB500_udpPort));
                    writeline(hReceiver, sprintf('TRACE:UDP:FLAG:ON "%s", %.0f, "VOLTage:AC", "OPT"', Localhost_publicIP, app.General.EB500_udpPort));

                case 'Close'
                    writeline(hReceiver, sprintf('TRACE:UDP:TAG:OFF "%s", %.0f, PSCAN',                Localhost_publicIP, app.General.EB500_udpPort));
                    writeline(hReceiver, sprintf('TRACE:UDP:FLAG:OFF "%s", %.0f, "VOLTage:AC", "OPT"', Localhost_publicIP, app.General.EB500_udpPort));
                    writeline(hReceiver, sprintf('TRACE:UDP:DEL "%s", %.0f',                           Localhost_publicIP, app.General.EB500_udpPort));
                    
                    flush(hStreaming)
            end
        end


        function RegularTask_ReloadTask(app, idx)
             % TENTATIVA DE INICAR A TAREFA (PENDENTE!!!)           
        end


        function Flag = RegularTask_StatusTaskCheck(app, idx)
            Flag = false;
            switch app.specObj(idx).Status
                case 'Na fila...'
                    if app.specObj(idx).Observation.BeginTime < datetime('now')
                        switch app.specObj(idx).taskObj.General.Task.Observation.Type
                            case {'Duration', 'Time'}
                                if isnat(app.specObj(idx).Observation.EndTime) | (app.specObj(idx).Observation.EndTime > datetime('now'))
                                    Flag = true;
                                end

                            case 'Samples'
                                Flag = true;
                        end
                    end

                    if Flag
                        % CRIAR ARQUIVOS AQUI!!! RETIRAR CRIAÇÃO DOS
                        % ARQUIVOS LÁ NO SPECOBJ
                        app.specObj(idx).Status = 'Em andamento...';
                    end
        
                case 'Em andamento...'
                    if app.specObj(idx).Observation.EndTime < datetime('now')
                        Flag = true;
                    else
                        if app.specObj(idx).taskObj.General.Task.Observation.Type == "Samples"
                            for ii = 1:numel(app.specObj(idx).Band)
                                if app.specObj(idx).Band(ii).File.WritedSamples == app.specObj(idx).taskObj.General.Task.Band(ii).instrObservationSamples
                                    app.specObj(idx) = RFlookBinLib.CloseFile(app.specObj(idx), ii);
                                end
                            end
                        end
                    end

                    if Flag
                        app.specObj(idx).Status = 'Concluída';
                    end

                case 'Erro'
                    if ~mod(minute(datetime('now')), 15)
                        RegularTask_ReloadTask(app, idx)

                        if app.specObj(idx).Status ~= "Erro"
                            Flag = true;
                        end
                    end
            end
        end


        function RegularTask_AntennaSwitch(app)
            % PENDENTE!!!
            % Se não conseguir configurar adequadamente, retorna um erro,
            % indicando o que foi tentado, o que obtido de resposta do
            % comutador ect.
        end


        function RegularTask_MainLoop(app)

            app.Flag_running = 1;
            app.Flag_editing = 1;
            
            while app.Flag_running
                if app.Flag_editing
                    app.timeObj = task_RevisitFactors(app.specObj);
                    
                    nn = 0;
                    app.Flag_editing = 0;
                end

                sweepTic = tic;
                for ii = 1:numel(app.specObj)
                    if RegularTask_StatusTaskCheck(app, ii)
                        app.Flag_editing = 1;
                        break
                    end

                    if app.specObj(ii).Status ~= "Em andamento..."
                        continue
                    end
                    
                    hReceiver  = app.specObj(ii).hReceiver;
                    hStreaming = app.specObj(ii).hStreaming;
                    hGPS       = app.specObj(ii).hGPS;
                    hSwitch    = app.specObj(ii).hSwitch;

                    nBands = numel(app.specObj(ii).Band);    
                    for jj = 0:nBands
                        if mod(nn, app.timeObj.Band(ii).RevisitFactor(jj+1))
                            continue
                        end
    
                        if jj == 0
                            app.specObj(ii).lastGPS = RegularTask_gpsData(app, ii, hReceiver, hGPS);

                        else
                            app.specObj(ii) = RFlookBinLib.CheckFile(app.specObj(ii), jj);
    
                            try
                                % ANTENNA SWITCH (IF APPLICABLE)
                                RegularTask_AntennaSwitch(app, ii, hSwitch)

                                % RECEIVER RECONFIGURATION (IF APPLICABLE)
                                if nBands > 1
                                    writeline(hReceiver, app.specObj(ii).Band(jj).scpiSet_Config);
                                    pause(.001)
                                    
                                    if ~isempty(app.specObj(ii).Band(jj).scpiSet_Att)
                                        writeline(hReceiver, app.specObj(ii).Band(jj).scpiSet_Att);
                                    end
                                end

                                newArray = RegularTask_specData(app, ii, jj, hReceiver, hStreaming);
                                    
                                attFactor = -1;
                                if strcmp(app.specObj(ii).taskObj.General.Task.Band(jj).instrAttMode, 'Auto')
                                    attFactor = str2double(writeread(hReceiver, app.specObj(ii).SCPI.scpiGet_Att));
                                end

                                % WATERFALL MATRIX
                                idx = app.specObj(ii).Band(jj).Watterfall.idx + 1;
                                if idx > app.General.Waterfall.Depth; idx = 1;
                                end

                                app.specObj(ii).Band(jj).Watterfall.idx = idx;
                                app.specObj(ii).Band(jj).Watterfall.Matrix(idx,:) = newArray;

                                % PLOT (IF APPLICABLE)
                                if (app.UITable.Selection == ii) & (app.Tree.SelectedNodes.NodeData == jj)
                                    RegularTask_PlotUpdate(app.specObj(ii), jj)
                                end
                                
                                % SPECTRAL MASK ANALYSIS (IF APPLICABLE)
                                if ~isempty(app.specObj(ii).Band(jj).Mask)
                                    smoothedArray = smoothdata(newArray);
                                    
                                    if any(smoothedArray - app.specInfo.mask{jj}.array > 0, 'all')
                                        app.specObj(ii).Band(jj).Mask.Count     = app.specObj(ii).Band(jj).Mask.Count + 1;
                                        app.specObj(ii).Band(jj).Mask.MainPeaks = task_FindPeaks(app.specObj(ii), jj, smoothedArray, app.General.FindPeaks);
                                    else
                                        continue
                                    end                                        
                                end
                                        
                                RFlookBinLib.EditFile(app.specObj(ii), jj, newArray, attFactor)
                                app.specObj(ii).Band(jj).File.WritedSamples = app.specObj(ii).Band(jj).File.WritedSamples + 1;
    
                            catch ME
                                % CONTADOR DE ERROS - SE CHEGAR A 10
                                % SUCESSIVOS (PRECISA ZERAR SE DEU CERTO), FAZ-SE UMA CHECAGEM NAS
                                % COISAS.
                                getReport(ME)
                            end
                        end
                    end
                end

                pause(app.timeObj.GlobalRevisitTime - toc(sweepTic))

            end

        end

    end
    
    methods (Access = public)
        
        function winAux_AddOrEditTask(app, taskObj)
            
            [app.specObj, idx] = Fcn_AddTask(app.specObj, taskObj);
            Fcn_TableBuilding(app, idx)

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.UIFigure.Visible = 0;            
            appName = 'appColeta_Task';
                        
            % PATH SEARCH
            Flag = 1;
            if isdeployed
                [~, result]    = system('path');
                app.RootFolder = char(regexpi(result, 'Path=(.*?);', 'tokens', 'once'));

                if ~isfile(fullfile(app.RootFolder, sprintf('%s.exe', appName)))
                    Flag = 0;
                    MessageBox(app, 'startup', 'Não identificado local de instalação do <i>app</i>.')
                end
                
            else
                prjPath = matlab.project.rootProject;
                appPath = fullfile(char(com.mathworks.appmanagement.MlappinstallUtil.getAppInstallationFolder), appName);
                
                if ~isempty(prjPath) & strcmp(prjPath.Name, appName)
                    app.RootFolder = char(prjPath.RootFolder);
                elseif isfolder(appPath)
                    app.RootFolder = appPath;                    
                else
                    Flag = 0;
                    MessageBox(app, 'startup', 'Não identificado local de instalação do <i>app</i>.')
                end
            end

            % INITIALIZATION            
            if Flag
                try
                    % <LOG>
                    diary(fullfile(app.RootFolder, 'Debug', '_log.txt'))
                    
                    diary on
                    fprintf(sprintf('<LogEntry>\n<BeginTime>%s</BeginTime>\n', datestr(now,'dd/mm/yyyy HH:MM:SS')))
                    % </LOG>

                    warning('off', 'MATLAB:ui:javaframe:PropertyToBeRemoved')
                    warning('off', 'MATLAB:subscripting:noSubscriptsSpecified')
                    warning('off', 'MATLAB:structOnObject')

                    startup_Layout(app)
                    startup_AxesCreation(app)
                    startup_ConfigFiles(app)

                catch ME
                    fprintf('%s\n', jsonencode(ME))
                    MessageBox(app, 'startup', getReport(ME))
                end

                app.UIFigure.Visible = 1;
                drawnow nocallbacks
            end
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            delete(app.auxWin1)
            delete(app.auxWin2)
            delete(app.auxWin3)

            delete(app)
            
        end

        % Selection changed function: UITable
        function UITableSelectionChanged(app, event)

            Fcn_TreeBuilding(app)
            
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)

%             idx1 = app.UITable.Selection;
%             idx2 = app.Tree.SelectedNodes.NodeData;
% 
%             FreqStart  = app.specObj(idx1).Task.Band(idx2).FreqStart;
%             FreqStop   = app.specObj(idx1).Task.Band(idx2).FreqStop;
%             DataPoints = app.specObj(idx1).Band(idx2).DataPoints;

%             xArray  = linspace(FreqStart, FreqStop, DataPoints);
%             yMatrix = app.specObj(idx1).Band(idx2).Matrix;

%             plot(app.axes1, xArray, randn(DataPoints, 1))
            
        end

        % Image clicked function: table_Edit, table_New
        function TableToolbarCallbacks(app, event)
            
            if isempty(app.auxWin1)
                app.d = uiprogressdlg(app.UIFigure, 'Indeterminate', 'on', 'Interpreter', 'html');
                app.d.Message = '<font style="font-size:12;">Em andamento...</font>';

                switch event.Source
                    case app.table_Edit; tempVar = struct('type', 'edit', 'idx', app.UITable.Selection);
                    otherwise;           tempVar = struct('type', 'new');                    
                end

                app.auxWin1 = winTask(app, tempVar);
                drawnow nocallbacks

%                 delete(d)
            else
                figure(app.auxWin1.UIFigure)
            end


%             app.Flag_editing = 1;
%             task_TableToolbarCallbacks(app, event)
% 
%             if ~app.Flag_running
%                 app.Flag_running = 1;
%             end

        end

        % Button pushed function: task_NameEdit
        function task_NameEditButtonPushed(app, event)
            
            if isempty(app.auxWin2)
                d = uiprogressdlg(app.UIFigure, 'Indeterminate', 'on', 'Interpreter', 'html', 'Message', '<font style="font-size:12;">Em andamento...</font>');

                app.auxWin2 = winTaskEdit(app);
                drawnow nocallbacks

                delete(d)                
            else
                figure(app.auxWin2.UIFigure)
            end

        end

        % Button pushed function: task_scpiListEdit
        function task_scpiListEditButtonPushed(app, event)
            
            if isempty(app.auxWin3)
                d = uiprogressdlg(app.UIFigure, 'Indeterminate', 'on', 'Interpreter', 'html', 'Message', '<font style="font-size:12;">Em andamento...</font>');

                app.auxWin3 = winScpiEdit(app);
                drawnow nocallbacks

                delete(d)
            else
                figure(app.auxWin3.UIFigure)
            end

        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            
            RegularTask_MainLoop(app)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appColeta: Tarefas';
            app.UIFigure.Icon = 'LR_icon.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.table_Grid = uigridlayout(app.GridLayout);
            app.UITable = uitable(app.table_Grid);
            app.UITable.ColumnName = {''; ''; ''; ''; ''; ''; ''; ''};
            app.UITable.RowName = {};
            app.UITable.SelectionType = 'row';
            app.UITable.SelectionChangedFcn = createCallbackFcn(app, @UITableSelectionChanged, true);
            app.UITable.Multiselect = 'off';
            app.Column1 = uilabel(app.table_Grid);
            app.Column1.Text = {'  ID'; '  '};
            app.Column2 = uilabel(app.table_Grid);
            app.Column2.Text = {'  Tarefa'; '  '};
            app.Column3 = uilabel(app.table_Grid);
            app.Column3.Text = {'   Inclusão'; '  '};
            app.Column7 = uilabel(app.table_Grid);
            app.Column7.Text = {'  Início'; '  Observação'};
            app.Column8 = uilabel(app.table_Grid);
            app.Column8.Text = {'  Fim'; '  Observação'};
            app.Column10 = uilabel(app.table_Grid);
            app.Column10.Text = {'  Estado'; '  '};
            app.Export_Grid = uigridlayout(app.table_Grid);
            app.table_Del = uiimage(app.Export_Grid);
            app.table_Del.Enable = 'off';
            app.table_Del.Tooltip = {'Excluir tarefa'};
            app.table_Del.ImageSource = 'LT_redX.png';
            app.table_New = uiimage(app.Export_Grid);
            app.table_New.ImageClickedFcn = createCallbackFcn(app, @TableToolbarCallbacks, true);
            app.table_New.Tooltip = {'Nova tarefa'};
            app.table_New.ImageSource = 'LT_AddFiles.png';
            app.table_Edit = uiimage(app.Export_Grid);
            app.table_Edit.ImageClickedFcn = createCallbackFcn(app, @TableToolbarCallbacks, true);
            app.table_Edit.Enable = 'off';
            app.table_Edit.Tooltip = {'Editar tarefa'};
            app.table_Edit.ImageSource = 'LT_edit.png';
            app.table_Stop = uiimage(app.Export_Grid);
            app.table_Stop.Enable = 'off';
            app.table_Stop.Tooltip = {'Parar tarefa'};
            app.table_Stop.ImageSource = 'LT_stop.png';
            app.table_Play = uiimage(app.Export_Grid);
            app.table_Play.Enable = 'off';
            app.table_Play.Tooltip = {'Executar tarefa'};
            app.table_Play.ImageSource = 'LT_play.png';
            app.table_Separator = uiimage(app.Export_Grid);
            app.table_Separator.ImageSource = 'LT_LineV.png';
            app.Column5 = uilabel(app.table_Grid);
            app.Column5.Text = {'  Receptor'; '  '};
            app.Column5_2 = uilabel(app.table_Grid);
            app.Column5_2.Text = {'  Recursos'; '  '};
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.task_scpiListEdit = uibutton(app.GridLayout3, 'push');
            app.task_scpiListEdit.ButtonPushedFcn = createCallbackFcn(app, @task_scpiListEditButtonPushed, true);
            app.task_scpiListEdit.Tag = 'task_Editable';
            app.task_scpiListEdit.Icon = 'LT_connect.png';
            app.task_scpiListEdit.Tooltip = {'Editar lista de instrumentos '; '(Analisador de espectro, receptor e GPS)'};
            app.task_scpiListEdit.Text = '';
            app.Image4 = uiimage(app.GridLayout3);
            app.Image4.ImageSource = 'tb_maxHold.gif';
            app.Image3 = uiimage(app.GridLayout3);
            app.Image3.ImageSource = 'tb_meanHold.gif';
            app.Image = uiimage(app.GridLayout3);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = 'LR_Persistance.png';
            app.Image2 = uiimage(app.GridLayout3);
            app.Image2.ImageSource = 'tb_minHold.gif';
            app.Image5 = uiimage(app.GridLayout3);
            app.Image5.ImageSource = 'LT_LineV.png';
            app.task_scpiListEdit_2 = uibutton(app.GridLayout3, 'push');
            app.task_scpiListEdit_2.Tag = 'task_Editable';
            app.task_scpiListEdit_2.Icon = 'tb_waterfall.gif';
            app.task_scpiListEdit_2.Tooltip = {'Editar lista de instrumentos '; '(Analisador de espectro, receptor e GPS)'};
            app.task_scpiListEdit_2.Text = '';
            app.task_NameEdit = uibutton(app.GridLayout3, 'push');
            app.task_NameEdit.ButtonPushedFcn = createCallbackFcn(app, @task_NameEditButtonPushed, true);
            app.task_NameEdit.Tag = 'task_Editable';
            app.task_NameEdit.Icon = 'LT_edit.png';
            app.task_NameEdit.Tooltip = {'Parametrização'; 'Tarefa SCPI'};
            app.task_NameEdit.Text = '';
            app.task_NameEdit_2 = uibutton(app.GridLayout3, 'push');
            app.task_NameEdit_2.Tag = 'task_Editable';
            app.task_NameEdit_2.Icon = 'LT_edit.png';
            app.task_NameEdit_2.Tooltip = {'Parametrização'; 'Tarefa SCPI'};
            app.task_NameEdit_2.Text = '';
            app.Image6 = uiimage(app.GridLayout3);
            app.Image6.ImageSource = 'LT_LineV.png';
            app.FaixasdefrequnciarelacionadastarefaselecionadaLabel = uilabel(app.GridLayout);
            app.FaixasdefrequnciarelacionadastarefaselecionadaLabel.Text = 'Faixa(s) de frequência relacionada(s) à tarefa selecionada:';
            app.Tree = uitree(app.GridLayout);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winMain

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end